classdef final_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NghiemTab                       matlab.ui.container.Tab
        TNbInput                        matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        TNaInput                        matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        TNPhuongTrinhInput              matlab.ui.control.NumericEditField
        NhpphngtrnhEditFieldLabel       matlab.ui.control.Label
        TNSaiSoInput                    matlab.ui.control.NumericEditField
        NhpsaischophpEditFieldLabel     matlab.ui.control.Label
        TNSoLanLapInput                 matlab.ui.control.NumericEditField
        SlnlpLabel                      matlab.ui.control.Label
        TNResultButton                  matlab.ui.control.NumericEditField
        KtqunghimLabel                  matlab.ui.control.Label
        TNOptions                       matlab.ui.control.DropDown
        ChnphngphptmnghimDropDownLabel  matlab.ui.control.Label
        TNButton                        matlab.ui.control.Button
        TNLabel                         matlab.ui.control.Label
        TNDrawPlotButton                matlab.ui.control.Button
        NoisuyTab                       matlab.ui.container.Tab
        NSButton                        matlab.ui.control.Button
        NSKetqua                        matlab.ui.control.NumericEditField
        KtqunisuyLabel                  matlab.ui.control.Label
        NSGiatri                        matlab.ui.control.NumericEditField
        NhpgitrcnnisuyLabel             matlab.ui.control.Label
        NSDathuc                        matlab.ui.control.EditField
        KtquathcnisuyLabel              matlab.ui.control.Label
        NSPP                            matlab.ui.control.DropDown
        PhngphpnisuyDropDownLabel       matlab.ui.control.Label
        NSNhapY                         matlab.ui.control.EditField
        NhpYLabel                       matlab.ui.control.Label
        NSNhapX                         matlab.ui.control.EditField
        NhpxLabel_2                     matlab.ui.control.Label
        NSUIAxes                        matlab.ui.control.UIAxes
        HoiquyTab                       matlab.ui.container.Tab
        HQMethod                        matlab.ui.control.DropDown
        HQCalcButton                    matlab.ui.control.Button
        HQKQDuDoan                      matlab.ui.control.NumericEditField
        KtqudonLabel                    matlab.ui.control.Label
        HQDuDoan                        matlab.ui.control.NumericEditField
        NhpgitrdonLabel                 matlab.ui.control.Label
        HQPhuongTrinh                   matlab.ui.control.EditField
        PhngtrnhhiquyLabel              matlab.ui.control.Label
        HQTextMethod                    matlab.ui.control.Label
        HQNhapY                         matlab.ui.control.EditField
        NhpyLabel                       matlab.ui.control.Label
        HQNhapX                         matlab.ui.control.EditField
        NhpxLabel                       matlab.ui.control.Label
        HQDoThi                         matlab.ui.control.UIAxes
        DaohamTab                       matlab.ui.container.Tab
        DHLamp                          matlab.ui.control.Lamp
        DHSwitch                        matlab.ui.control.Switch
        TimaohamquahamsSwitchLabel      matlab.ui.control.Label
        DHInputFx                       matlab.ui.control.EditField
        NhpvaohamsfxLabel               matlab.ui.control.Label
        DHInputY                        matlab.ui.control.EditField
        NhpvaodliuyEditFieldLabel       matlab.ui.control.Label
        DHInputX                        matlab.ui.control.EditField
        NhpvaodliuxEditFieldLabel       matlab.ui.control.Label
        DHSaiSoOptions                  matlab.ui.control.DropDown
        ChongiatrisaisOhhocOh2DropDownLabel  matlab.ui.control.Label
        DHCalcButton                    matlab.ui.control.Button
        DHResult                        matlab.ui.control.NumericEditField
        KtquaEditFieldLabel             matlab.ui.control.Label
        DHCalculateValue                matlab.ui.control.NumericEditField
        NhpgiatricntinhaohamEditFieldLabel  matlab.ui.control.Label
        DHDeriveMethodsOption           matlab.ui.control.DropDown
        ChonphngphapaohamXpxitrnluitrungtmDropDownLabel  matlab.ui.control.Label
        NhpvaodliuyLabel_2              matlab.ui.control.Label
        DHInputStep                     matlab.ui.control.NumericEditField
        TichphanTab                     matlab.ui.container.Tab
        TPNhapN                         matlab.ui.control.NumericEditField
        NhpNLabel                       matlab.ui.control.Label
        TPOnOff                         matlab.ui.control.Switch
        TimaohamquahamsSwitchLabel_2    matlab.ui.control.Label
        TPNhaphamso                     matlab.ui.control.EditField
        NhpvaohamsfxLabel_2             matlab.ui.control.Label
        TPNhapY                         matlab.ui.control.EditField
        NhpvaodliuyEditFieldLabel_2     matlab.ui.control.Label
        TPNhapX                         matlab.ui.control.EditField
        NhpvaodliuxEditFieldLabel_2     matlab.ui.control.Label
        TPKetqua                        matlab.ui.control.NumericEditField
        KtquaEditFieldLabel_2           matlab.ui.control.Label
        TPNhapcan                       matlab.ui.control.NumericEditField
        NhpcntnhtchphnLabel             matlab.ui.control.Label
        TPChonPP                        matlab.ui.control.DropDown
        ChonphngphapTchphnHnhthangSimpson13Simpson38Label  matlab.ui.control.Label
        TPCalcButton                    matlab.ui.control.Button
        GioiThieuNhomTab                matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        NhmCamelCupLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        DerivativeObject % Description
        
        HoiquyObject % Description
    end
    
    methods (Access = private)
        
        %Convert string to vector.
        function vec = str2vector(app, source)
            num = textscan(source, '%f', 'Delimiter',',');
            vec = num{1}';
        end

        % Modify the visibility of DH tab.
        function modifyDHTab(app, xEnable, yEnable, lampColor, stepEnable, fxEnable, optionsEnable)
            app.DHInputX.Enable = xEnable;
            app.DHInputY.Enable = yEnable;
            app.DHLamp.Color = lampColor;

            app.DHInputStep.Enable = stepEnable;
            app.DHInputFx.Enable = fxEnable;
            app.DHSaiSoOptions.Enable = optionsEnable;
        end

        %Startup function for DH tab. This function will create a
        %derivative object and disable find derivative using fx.
        function DHStartUpFcn(app)
           app.DerivativeObject = Derivative;
           app.DerivativeObject.switchState = 0; % Turn off
           app.DerivativeObject.derivativeMethod = 0;
           app.DerivativeObject.Oh = 0;
           app.DHSwitch.Value = 'Off';
           modifyDHTab(app, 'on', 'on', [0.50,0.50,0.50], 'off', 'off', 'off');
        end
        
        
        function danhSachThanhVienStarUpFcn(app)
            danhSach = readtable("DSTV.xlsx","Sheet",1);
            app.UITable.Data = danhSach;
        end
        
        function HQStartUpFcn(app)
            app.HoiquyObject = Hoiquy;
            
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            DHStartUpFcn(app);
            HQStartUpFcn(app);
            danhSachThanhVienStarUpFcn(app);
        end

        % Value changed function: DHSwitch
        function DHSwitchValueChanged(app, event)
            value = app.DHSwitch.Value;

            if strcmp(value,'Off')
                app.DerivativeObject.switchState = 0;
                modifyDHTab(app, 'on', 'on', [0.50,0.50,0.50], 'off', 'off', 'off');
                app.DHInputFx.Value = '';
                app.DHInputStep.Value = 0;
            elseif strcmp(value,'On')
                app.DerivativeObject.switchState = 1;
                modifyDHTab(app, 'off', 'off', 'g', 'on', 'on', 'on');
                app.DHInputX.Value = '';
                app.DHInputY.Value = '';
            end
        end

        % Value changed function: DHInputX
        function DHInputXValueChanged(app, event)
            value = app.DHInputX.Value;
            
            vec = str2vector(app, value);
            app.DerivativeObject.xa = vec;
        end

        % Value changed function: DHInputY
        function DHInputYValueChanged(app, event)
            value = app.DHInputY.Value;
            
            vec = str2vector(app, value);
            app.DerivativeObject.ya = vec;
        end

        % Value changed function: DHInputFx
        function DHInputFxValueChanged(app, event)
            value = app.DHInputFx.Value;
            
            syms x;
            f = str2func(['@(x)', value]);
            app.DerivativeObject.fx = f;        
        end

        % Value changed function: DHInputStep
        function DHInputStepValueChanged(app, event)
            value = app.DHInputStep.Value;
            
            app.DerivativeObject.h = value;
        end

        % Value changed function: DHSaiSoOptions
        function DHSaiSoOptionsValueChanged(app, event)
            value = app.DHSaiSoOptions.Value;
            
            if strcmp(value, 'O(h)')
                app.DerivativeObject.Oh = 0; %O(h)
            else 
                app.DerivativeObject.Oh = 1; %O(h^2)
            end
        end

        % Button pushed function: DHCalcButton
        function DHCalcButtonPushed(app, event)
            if app.DerivativeObject.switchState == 0
                result = app.DerivativeObject.LagrangeDerivative();           
            else
                result = app.DerivativeObject.fxDerivative();
            end
            app.DHResult.Value = result;
        end

        % Value changed function: DHCalculateValue
        function DHCalculateValueValueChanged(app, event)
            value = app.DHCalculateValue.Value;
            
            app.DerivativeObject.x = value;
        end

        % Value changed function: DHDeriveMethodsOption
        function DHDeriveMethodsOptionValueChanged(app, event)
            value = app.DHDeriveMethodsOption.Value;
            
            if strcmp(value, 'Xấp xỉ tiến')
                app.DerivativeObject.derivativeMethod = 0; %Xap xi tren
            elseif strcmp(value, 'Xấp xỉ lùi')
                app.DerivativeObject.derivativeMethod = 1; %Xap xi lui
            else
                app.DerivativeObject.derivativeMethod = 2; %Xap xi trung tam
            end
        end

        % Value changed function: HQNhapX
        function HQNhapXValueChanged(app, event)
            value = app.HQNhapX.Value;
            
            vec = str2vector(app, value);
            app.HoiquyObject.xa = vec;
        end

        % Value changed function: HQNhapY
        function HQNhapYValueChanged(app, event)
            value = app.HQNhapY.Value;
            
            vec = str2vector(app, value);
            app.HoiquyObject.ya = vec;
        end

        % Value changed function: HQDuDoan
        function HQDuDoanValueChanged(app, event)
            value = app.HQDuDoan.Value;
            app.HoiquyObject.inDuDoan = value;
        end

        % Value changed function: HQKQDuDoan
        function HQKQDuDoanValueChanged(app, event)
            value = app.HQKQDuDoan.Value;
            app.HoiquyObject.outDuDoan = value;
        end

        % Value changed function: HQPhuongTrinh
        function HQPhuongTrinhValueChanged(app, event)
            value = app.HQPhuongTrinh.Value;

            syms x;
            f = str2func(['@(x)', value]);
            app.HoiquyObject.phuongTrinh = f;
        end

        % Value changed function: HQMethod
        function HQMethodValueChanged(app, event)
            value = app.HQMethod.Value;
            
            if strcmp(value, 'Hồi quy tuyến tính')
                app.HoiquyObject.ppHoiQuy = 0; %Hoi quy tuyen tinh
            elseif strcmp(value, 'Hồi quy hàm mũ')
                app.HoiquyObject.ppHoiQuy = 1; %Hoi quy ham mu
            else
                app.HoiquyObject.ppHoiQuy = 2; %Hoi quy ham logarit
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghiemTab = uitab(app.TabGroup);
            app.NghiemTab.Title = 'Nghiệm';
            app.TNDrawPlotButton = uibutton(app.NghiemTab, 'push');
            app.TNDrawPlotButton.Text = 'Vẽ đồ thị hàm số';
            app.TNLabel = uilabel(app.NghiemTab);
            app.TNLabel.Text = 'Nhập khoảng phân ly nghiệm [a,b]:';
            app.TNButton = uibutton(app.NghiemTab, 'push');
            app.TNButton.Text = 'Tìm nghiệm';
            app.ChnphngphptmnghimDropDownLabel = uilabel(app.NghiemTab);
            app.ChnphngphptmnghimDropDownLabel.Text = 'Chọn phương pháp tìm nghiệm';
            app.TNOptions = uidropdown(app.NghiemTab);
            app.TNOptions.Items = {'Chia đôi', 'Lặp', 'Newton'};
            app.TNOptions.Value = 'Chia đôi';
            app.KtqunghimLabel = uilabel(app.NghiemTab);
            app.KtqunghimLabel.Text = 'Kết quả nghiệm:';
            app.TNResultButton = uieditfield(app.NghiemTab, 'numeric');
            app.SlnlpLabel = uilabel(app.NghiemTab);
            app.SlnlpLabel.Text = 'Số lần lặp:';
            app.TNSoLanLapInput = uieditfield(app.NghiemTab, 'numeric');
            app.NhpsaischophpEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpsaischophpEditFieldLabel.Text = 'Nhập sai số cho phép';
            app.TNSaiSoInput = uieditfield(app.NghiemTab, 'numeric');
            app.NhpphngtrnhEditFieldLabel = uilabel(app.NghiemTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.TNPhuongTrinhInput = uieditfield(app.NghiemTab, 'numeric');
            app.aEditFieldLabel = uilabel(app.NghiemTab);
            app.aEditFieldLabel.Text = 'a:';
            app.TNaInput = uieditfield(app.NghiemTab, 'numeric');
            app.bEditFieldLabel = uilabel(app.NghiemTab);
            app.bEditFieldLabel.Text = 'b:';
            app.TNbInput = uieditfield(app.NghiemTab, 'numeric');
            app.NoisuyTab = uitab(app.TabGroup);
            app.NoisuyTab.Title = 'Nội suy';
            app.NSUIAxes = uiaxes(app.NoisuyTab);
            title(app.NSUIAxes, 'Đồ thị hàm số nội suy và dữ liệu thực')
            xlabel(app.NSUIAxes, 'X')
            ylabel(app.NSUIAxes, 'Y')
            zlabel(app.NSUIAxes, 'Z')
            app.NhpxLabel_2 = uilabel(app.NoisuyTab);
            app.NhpxLabel_2.Text = 'Nhập x';
            app.NSNhapX = uieditfield(app.NoisuyTab, 'text');
            app.NhpYLabel = uilabel(app.NoisuyTab);
            app.NhpYLabel.Text = 'Nhập y';
            app.NSNhapY = uieditfield(app.NoisuyTab, 'text');
            app.PhngphpnisuyDropDownLabel = uilabel(app.NoisuyTab);
            app.PhngphpnisuyDropDownLabel.Text = 'Phương pháp  nội suy';
            app.NSPP = uidropdown(app.NoisuyTab);
            app.NSPP.Items = {'Newton', 'Larange'};
            app.NSPP.Value = 'Newton';
            app.KtquathcnisuyLabel = uilabel(app.NoisuyTab);
            app.KtquathcnisuyLabel.Text = 'Kết quả đa thức nội suy';
            app.NSDathuc = uieditfield(app.NoisuyTab, 'text');
            app.NhpgitrcnnisuyLabel = uilabel(app.NoisuyTab);
            app.NhpgitrcnnisuyLabel.Text = 'Nhập giá trị cần nội suy';
            app.NSGiatri = uieditfield(app.NoisuyTab, 'numeric');
            app.KtqunisuyLabel = uilabel(app.NoisuyTab);
            app.KtqunisuyLabel.Text = 'Kết quả nội suy';
            app.NSKetqua = uieditfield(app.NoisuyTab, 'numeric');
            app.NSButton = uibutton(app.NoisuyTab, 'push');
            app.NSButton.Text = 'Tính';
            app.HoiquyTab = uitab(app.TabGroup);
            app.HoiquyTab.Title = 'Hồi quy';
            app.HQDoThi = uiaxes(app.HoiquyTab);
            title(app.HQDoThi, 'Đồ thị hồi quy')
            xlabel(app.HQDoThi, 'X')
            ylabel(app.HQDoThi, 'Y')
            zlabel(app.HQDoThi, 'Z')
            app.NhpxLabel = uilabel(app.HoiquyTab);
            app.NhpxLabel.Text = 'Nhập x';
            app.HQNhapX = uieditfield(app.HoiquyTab, 'text');
            app.HQNhapX.ValueChangedFcn = createCallbackFcn(app, @HQNhapXValueChanged, true);
            app.NhpyLabel = uilabel(app.HoiquyTab);
            app.NhpyLabel.Text = 'Nhập y';
            app.HQNhapY = uieditfield(app.HoiquyTab, 'text');
            app.HQNhapY.ValueChangedFcn = createCallbackFcn(app, @HQNhapYValueChanged, true);
            app.HQTextMethod = uilabel(app.HoiquyTab);
            app.HQTextMethod.Text = 'Chọn phương pháp hồi quy';
            app.PhngtrnhhiquyLabel = uilabel(app.HoiquyTab);
            app.PhngtrnhhiquyLabel.Text = 'Phương trình hồi quy';
            app.HQPhuongTrinh = uieditfield(app.HoiquyTab, 'text');
            app.HQPhuongTrinh.ValueChangedFcn = createCallbackFcn(app, @HQPhuongTrinhValueChanged, true);
            app.NhpgitrdonLabel = uilabel(app.HoiquyTab);
            app.NhpgitrdonLabel.Text = 'Nhập giá trị dự đoán';
            app.HQDuDoan = uieditfield(app.HoiquyTab, 'numeric');
            app.HQDuDoan.ValueChangedFcn = createCallbackFcn(app, @HQDuDoanValueChanged, true);
            app.KtqudonLabel = uilabel(app.HoiquyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.HQKQDuDoan = uieditfield(app.HoiquyTab, 'numeric');
            app.HQKQDuDoan.ValueChangedFcn = createCallbackFcn(app, @HQKQDuDoanValueChanged, true);
            app.HQCalcButton = uibutton(app.HoiquyTab, 'push');
            app.HQCalcButton.Text = 'Tính';
            app.HQMethod = uidropdown(app.HoiquyTab);
            app.HQMethod.Items = {'Hồi quy tuyến tính ', 'Hồi quy hàm mũ ', 'Hồi quy hàm logarit '};
            app.HQMethod.ValueChangedFcn = createCallbackFcn(app, @HQMethodValueChanged, true);
            app.HQMethod.Value = 'Hồi quy tuyến tính ';
            app.DaohamTab = uitab(app.TabGroup);
            app.DaohamTab.Title = 'Đạo hàm';
            app.DHInputStep = uieditfield(app.DaohamTab, 'numeric');
            app.DHInputStep.ValueChangedFcn = createCallbackFcn(app, @DHInputStepValueChanged, true);
            app.NhpvaodliuyLabel_2 = uilabel(app.DaohamTab);
            app.NhpvaodliuyLabel_2.Text = 'Nhập vào bước h';
            app.ChonphngphapaohamXpxitrnluitrungtmDropDownLabel = uilabel(app.DaohamTab);
            app.ChonphngphapaohamXpxitrnluitrungtmDropDownLabel.Text = {'Chọn phương pháp đạo hàm: Xấp xỉ trên,'; 'lùi, trung tâm'};
            app.DHDeriveMethodsOption = uidropdown(app.DaohamTab);
            app.DHDeriveMethodsOption.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.DHDeriveMethodsOption.ValueChangedFcn = createCallbackFcn(app, @DHDeriveMethodsOptionValueChanged, true);
            app.DHDeriveMethodsOption.Value = 'Xấp xỉ tiến';
            app.NhpgiatricntinhaohamEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpgiatricntinhaohamEditFieldLabel.Text = 'Nhập giá trị cần tính đạo hàm';
            app.DHCalculateValue = uieditfield(app.DaohamTab, 'numeric');
            app.DHCalculateValue.ValueChangedFcn = createCallbackFcn(app, @DHCalculateValueValueChanged, true);
            app.KtquaEditFieldLabel = uilabel(app.DaohamTab);
            app.KtquaEditFieldLabel.Text = 'Kết quả';
            app.DHResult = uieditfield(app.DaohamTab, 'numeric');
            app.DHResult.Editable = 'off';
            app.DHCalcButton = uibutton(app.DaohamTab, 'push');
            app.DHCalcButton.ButtonPushedFcn = createCallbackFcn(app, @DHCalcButtonPushed, true);
            app.DHCalcButton.Text = 'Tính';
            app.ChongiatrisaisOhhocOh2DropDownLabel = uilabel(app.DaohamTab);
            app.ChongiatrisaisOhhocOh2DropDownLabel.Text = {'Chọn giá trị sai số O(h) '; 'hoặc O(h^2) '};
            app.DHSaiSoOptions = uidropdown(app.DaohamTab);
            app.DHSaiSoOptions.Items = {'O(h)', 'O(h^2)'};
            app.DHSaiSoOptions.ValueChangedFcn = createCallbackFcn(app, @DHSaiSoOptionsValueChanged, true);
            app.DHSaiSoOptions.Value = 'O(h)';
            app.NhpvaodliuxEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpvaodliuxEditFieldLabel.Text = 'Nhập vào dữ liệu x';
            app.DHInputX = uieditfield(app.DaohamTab, 'text');
            app.DHInputX.ValueChangedFcn = createCallbackFcn(app, @DHInputXValueChanged, true);
            app.NhpvaodliuyEditFieldLabel = uilabel(app.DaohamTab);
            app.NhpvaodliuyEditFieldLabel.Text = 'Nhập vào dữ liệu y';
            app.DHInputY = uieditfield(app.DaohamTab, 'text');
            app.DHInputY.ValueChangedFcn = createCallbackFcn(app, @DHInputYValueChanged, true);
            app.NhpvaohamsfxLabel = uilabel(app.DaohamTab);
            app.NhpvaohamsfxLabel.Text = 'Nhập vào hàm số f(x)';
            app.DHInputFx = uieditfield(app.DaohamTab, 'text');
            app.DHInputFx.ValueChangedFcn = createCallbackFcn(app, @DHInputFxValueChanged, true);
            app.TimaohamquahamsSwitchLabel = uilabel(app.DaohamTab);
            app.TimaohamquahamsSwitchLabel.Text = 'Tìm đạo hàm qua hàm số';
            app.DHSwitch = uiswitch(app.DaohamTab, 'slider');
            app.DHSwitch.ValueChangedFcn = createCallbackFcn(app, @DHSwitchValueChanged, true);
            app.DHLamp = uilamp(app.DaohamTab);
            app.TichphanTab = uitab(app.TabGroup);
            app.TichphanTab.Title = 'Tích phân';
            app.TPCalcButton = uibutton(app.TichphanTab, 'push');
            app.TPCalcButton.Text = 'Tính';
            app.ChonphngphapTchphnHnhthangSimpson13Simpson38Label = uilabel(app.TichphanTab);
            app.ChonphngphapTchphnHnhthangSimpson13Simpson38Label.Text = {'Chọn phương pháp Tích phân: Hình thang,'; 'Simpson 1/3 , Simpson 3/8'};
            app.TPChonPP = uidropdown(app.TichphanTab);
            app.TPChonPP.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.TPChonPP.Value = 'Simpson 1/3';
            app.NhpcntnhtchphnLabel = uilabel(app.TichphanTab);
            app.NhpcntnhtchphnLabel.Text = 'Nhập cận để tính tích phân';
            app.TPNhapcan = uieditfield(app.TichphanTab, 'numeric');
            app.KtquaEditFieldLabel_2 = uilabel(app.TichphanTab);
            app.KtquaEditFieldLabel_2.Text = 'Kết quả';
            app.TPKetqua = uieditfield(app.TichphanTab, 'numeric');
            app.NhpvaodliuxEditFieldLabel_2 = uilabel(app.TichphanTab);
            app.NhpvaodliuxEditFieldLabel_2.Text = 'Nhập vào dữ liệu x';
            app.TPNhapX = uieditfield(app.TichphanTab, 'text');
            app.NhpvaodliuyEditFieldLabel_2 = uilabel(app.TichphanTab);
            app.NhpvaodliuyEditFieldLabel_2.Text = 'Nhập vào dữ liệu y';
            app.TPNhapY = uieditfield(app.TichphanTab, 'text');
            app.NhpvaohamsfxLabel_2 = uilabel(app.TichphanTab);
            app.NhpvaohamsfxLabel_2.Text = 'Nhập vào hàm số f(x)';
            app.TPNhaphamso = uieditfield(app.TichphanTab, 'text');
            app.TimaohamquahamsSwitchLabel_2 = uilabel(app.TichphanTab);
            app.TimaohamquahamsSwitchLabel_2.Text = 'Tìm đạo hàm qua hàm số';
            app.TPOnOff = uiswitch(app.TichphanTab, 'slider');
            app.NhpNLabel = uilabel(app.TichphanTab);
            app.NhpNLabel.Text = 'Nhập N';
            app.TPNhapN = uieditfield(app.TichphanTab, 'numeric');
            app.GioiThieuNhomTab = uitab(app.TabGroup);
            app.GioiThieuNhomTab.Title = 'Giới thiệu nhóm';
            app.NhmCamelCupLabel = uilabel(app.GioiThieuNhomTab);
            app.NhmCamelCupLabel.Text = 'Nhóm CamelCup';
            app.UITable = uitable(app.GioiThieuNhomTab);
            app.UITable.ColumnName = {'STT'; 'MSSV'; 'Họ và tên'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = final_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end